classdef PlayingSong_Part_E < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MusicPlayerUIFigure         matlab.ui.Figure
        MusicPlayerLabel            matlab.ui.control.Label
        StopSoundsButton            matlab.ui.control.Button
        PlayTwinklenoADSRButton     matlab.ui.control.Button
        PlayTwinkleTwinkleButton    matlab.ui.control.Button
        ReleaseTime                 matlab.ui.control.NumericEditField
        SustainTime                 matlab.ui.control.NumericEditField
        DecayTime                   matlab.ui.control.NumericEditField
        ReleaseLevel                matlab.ui.control.NumericEditField
        DecayLevel                  matlab.ui.control.NumericEditField
        AttackLevel                 matlab.ui.control.NumericEditField
        SustainLevel                matlab.ui.control.NumericEditField
        AttackTime                  matlab.ui.control.NumericEditField
        ReleaseTimeSlider           matlab.ui.control.Slider
        ReleaseTimeSliderLabel      matlab.ui.control.Label
        SustainTimeSlider           matlab.ui.control.Slider
        SustainTimeSliderLabel      matlab.ui.control.Label
        DecayTimeSlider             matlab.ui.control.Slider
        DecayTimeSliderLabel        matlab.ui.control.Label
        AttackTimeSlider            matlab.ui.control.Slider
        AttackTimeSliderLabel       matlab.ui.control.Label
        FinalReleaseSlider          matlab.ui.control.Slider
        FinalReleaseSliderLabel     matlab.ui.control.Label
        FinalSustainSlider          matlab.ui.control.Slider
        FinalSustainSliderLabel     matlab.ui.control.Label
        FinalDecaySlider            matlab.ui.control.Slider
        FinalDecaySliderLabel       matlab.ui.control.Label
        FinalAttackSlider           matlab.ui.control.Slider
        FinalAttackSliderLabel      matlab.ui.control.Label
        BPMEditField                matlab.ui.control.NumericEditField
        BPMEditFieldLabel           matlab.ui.control.Label
        SampleRateHzEditField       matlab.ui.control.NumericEditField
        SampleRateHzEditFieldLabel  matlab.ui.control.Label
        PlayFurEliseButton          matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ADSR_PLOT = adsrplot(app.SampleRateHzEditField.Value,[app.AttackLevel.Value,app.DecayLevel.Value,app.SustainLevel.Value,app.ReleaseLevel.Value], [app.AttackTime.Value,app.DecayTime.Value,app.SustainTime.Value,app.ReleaseTime.Value]);
            plot(app.UIAxes,ADSR_PLOT);
        end

        % Button pushed function: PlayFurEliseButton
        function PlayFurEliseButtonPushed(app, event)
            clear sound
            PlayFurElise(app.SampleRateHzEditField.Value, app.BPMEditField.Value, [app.AttackLevel.Value,app.DecayLevel.Value,app.SustainLevel.Value,app.ReleaseLevel.Value], [app.AttackTime.Value,app.DecayTime.Value,app.SustainTime.Value,app.ReleaseTime.Value]);
        end

        % Value changed function: FinalAttackSlider
        function FinalAttackSliderValueChanged(app, event)
            value = app.FinalAttackSlider.Value;
            app.AttackLevel.Value = value;
            ADSR_PLOT = adsrplot(app.SampleRateHzEditField.Value,[app.AttackLevel.Value,app.DecayLevel.Value,app.SustainLevel.Value,app.ReleaseLevel.Value], [app.AttackTime.Value,app.DecayTime.Value,app.SustainTime.Value,app.ReleaseTime.Value]);
            plot(app.UIAxes,ADSR_PLOT);
        end

        % Value changed function: FinalDecaySlider
        function FinalDecaySliderValueChanged(app, event)
            value = app.FinalDecaySlider.Value;
            app.DecayLevel.Value = value;
            ADSR_PLOT = adsrplot(app.SampleRateHzEditField.Value,[app.AttackLevel.Value,app.DecayLevel.Value,app.SustainLevel.Value,app.ReleaseLevel.Value], [app.AttackTime.Value,app.DecayTime.Value,app.SustainTime.Value,app.ReleaseTime.Value]);
            plot(app.UIAxes,ADSR_PLOT);
        end

        % Value changed function: FinalSustainSlider
        function FinalSustainSliderValueChanged(app, event)
            value = app.FinalSustainSlider.Value;
            app.SustainLevel.Value = value;
            ADSR_PLOT = adsrplot(app.SampleRateHzEditField.Value,[app.AttackLevel.Value,app.DecayLevel.Value,app.SustainLevel.Value,app.ReleaseLevel.Value], [app.AttackTime.Value,app.DecayTime.Value,app.SustainTime.Value,app.ReleaseTime.Value]);
            plot(app.UIAxes,ADSR_PLOT);
        end

        % Value changed function: FinalReleaseSlider
        function FinalReleaseSliderValueChanged(app, event)
            value = app.FinalReleaseSlider.Value;
            app.ReleaseLevel.Value = value;
            ADSR_PLOT = adsrplot(app.SampleRateHzEditField.Value,[app.AttackLevel.Value,app.DecayLevel.Value,app.SustainLevel.Value,app.ReleaseLevel.Value], [app.AttackTime.Value,app.DecayTime.Value,app.SustainTime.Value,app.ReleaseTime.Value]);
            plot(app.UIAxes,ADSR_PLOT);
        end

        % Value changed function: AttackTimeSlider
        function AttackTimeSliderValueChanged(app, event)
            value = app.AttackTimeSlider.Value;
            app.AttackTime.Value = value;
            ADSR_PLOT = adsrplot(app.SampleRateHzEditField.Value,[app.AttackLevel.Value,app.DecayLevel.Value,app.SustainLevel.Value,app.ReleaseLevel.Value], [app.AttackTime.Value,app.DecayTime.Value,app.SustainTime.Value,app.ReleaseTime.Value]);
            plot(app.UIAxes,ADSR_PLOT);
        end

        % Value changed function: DecayTimeSlider
        function DecayTimeSliderValueChanged(app, event)
            value = app.DecayTimeSlider.Value;
            app.DecayTime.Value = value;
            ADSR_PLOT = adsrplot(app.SampleRateHzEditField.Value,[app.AttackLevel.Value,app.DecayLevel.Value,app.SustainLevel.Value,app.ReleaseLevel.Value], [app.AttackTime.Value,app.DecayTime.Value,app.SustainTime.Value,app.ReleaseTime.Value]);
            plot(app.UIAxes,ADSR_PLOT);
        end

        % Value changed function: SustainTimeSlider
        function SustainTimeSliderValueChanged(app, event)
            value = app.SustainTimeSlider.Value;
            app.SustainTime.Value = value;
            ADSR_PLOT = adsrplot(app.SampleRateHzEditField.Value,[app.AttackLevel.Value,app.DecayLevel.Value,app.SustainLevel.Value,app.ReleaseLevel.Value], [app.AttackTime.Value,app.DecayTime.Value,app.SustainTime.Value,app.ReleaseTime.Value]);
            plot(app.UIAxes,ADSR_PLOT);
        end

        % Value changed function: ReleaseTimeSlider
        function ReleaseTimeSliderValueChanged(app, event)
            value = app.ReleaseTimeSlider.Value;
            app.ReleaseTime.Value = value;
            ADSR_PLOT = adsrplot(app.SampleRateHzEditField.Value,[app.AttackLevel.Value,app.DecayLevel.Value,app.SustainLevel.Value,app.ReleaseLevel.Value], [app.AttackTime.Value,app.DecayTime.Value,app.SustainTime.Value,app.ReleaseTime.Value]);
            plot(app.UIAxes,ADSR_PLOT);
        end

        % Button pushed function: PlayTwinkleTwinkleButton
        function PlayTwinkleTwinkleButtonPushed(app, event)
           clear sound
           PlayTwinkle(app.SampleRateHzEditField.Value, app.BPMEditField.Value, [app.AttackLevel.Value,app.DecayLevel.Value,app.SustainLevel.Value,app.ReleaseLevel.Value], [app.AttackTime.Value,app.DecayTime.Value,app.SustainTime.Value,app.ReleaseTime.Value]);
        end

        % Button pushed function: StopSoundsButton
        function StopSoundsButtonPushed(app, event)
            clear sound
        end

        % Value changed function: AttackLevel
        function AttackLevelValueChanged(app, event)
            value = app.AttackLevel.Value;
            ADSR_PLOT = adsrplot(app.SampleRateHzEditField.Value,[app.AttackLevel.Value,app.DecayLevel.Value,app.SustainLevel.Value,app.ReleaseLevel.Value], [app.AttackTime.Value,app.DecayTime.Value,app.SustainTime.Value,app.ReleaseTime.Value]);
            plot(app.UIAxes,ADSR_PLOT);
        end

        % Value changed function: DecayLevel
        function DecayLevelValueChanged(app, event)
            value = app.DecayLevel.Value;
            ADSR_PLOT = adsrplot(app.SampleRateHzEditField.Value,[app.AttackLevel.Value,app.DecayLevel.Value,app.SustainLevel.Value,app.ReleaseLevel.Value], [app.AttackTime.Value,app.DecayTime.Value,app.SustainTime.Value,app.ReleaseTime.Value]);
            plot(app.UIAxes,ADSR_PLOT);
        end

        % Value changed function: SustainLevel
        function SustainLevelValueChanged(app, event)
            value = app.SustainLevel.Value;
            ADSR_PLOT = adsrplot(app.SampleRateHzEditField.Value,[app.AttackLevel.Value,app.DecayLevel.Value,app.SustainLevel.Value,app.ReleaseLevel.Value], [app.AttackTime.Value,app.DecayTime.Value,app.SustainTime.Value,app.ReleaseTime.Value]);
            plot(app.UIAxes,ADSR_PLOT);
        end

        % Value changed function: ReleaseLevel
        function ReleaseLevelValueChanged(app, event)
            value = app.ReleaseLevel.Value;
            ADSR_PLOT = adsrplot(app.SampleRateHzEditField.Value,[app.AttackLevel.Value,app.DecayLevel.Value,app.SustainLevel.Value,app.ReleaseLevel.Value], [app.AttackTime.Value,app.DecayTime.Value,app.SustainTime.Value,app.ReleaseTime.Value]);
            plot(app.UIAxes,ADSR_PLOT);
        end

        % Value changed function: AttackTime
        function AttackTimeValueChanged(app, event)
            value = app.AttackTime.Value;
            ADSR_PLOT = adsrplot(app.SampleRateHzEditField.Value,[app.AttackLevel.Value,app.DecayLevel.Value,app.SustainLevel.Value,app.ReleaseLevel.Value], [app.AttackTime.Value,app.DecayTime.Value,app.SustainTime.Value,app.ReleaseTime.Value]);
            plot(app.UIAxes,ADSR_PLOT);
        end

        % Value changed function: DecayTime
        function DecayTimeValueChanged(app, event)
            value = app.DecayTime.Value;
            ADSR_PLOT = adsrplot(app.SampleRateHzEditField.Value,[app.AttackLevel.Value,app.DecayLevel.Value,app.SustainLevel.Value,app.ReleaseLevel.Value], [app.AttackTime.Value,app.DecayTime.Value,app.SustainTime.Value,app.ReleaseTime.Value]);
            plot(app.UIAxes,ADSR_PLOT);
        end

        % Value changed function: SustainTime
        function SustainTimeValueChanged(app, event)
            value = app.SustainTime.Value;
            ADSR_PLOT = adsrplot(app.SampleRateHzEditField.Value,[app.AttackLevel.Value,app.DecayLevel.Value,app.SustainLevel.Value,app.ReleaseLevel.Value], [app.AttackTime.Value,app.DecayTime.Value,app.SustainTime.Value,app.ReleaseTime.Value]);
            plot(app.UIAxes,ADSR_PLOT);
        end

        % Value changed function: ReleaseTime
        function ReleaseTimeValueChanged(app, event)
            value = app.ReleaseTime.Value;
            ADSR_PLOT = adsrplot(app.SampleRateHzEditField.Value,[app.AttackLevel.Value,app.DecayLevel.Value,app.SustainLevel.Value,app.ReleaseLevel.Value], [app.AttackTime.Value,app.DecayTime.Value,app.SustainTime.Value,app.ReleaseTime.Value]);
            plot(app.UIAxes,ADSR_PLOT);
        end

        % Button pushed function: PlayTwinklenoADSRButton
        function PlayTwinklenoADSRButtonPushed(app, event)
           clear sound
           PlayTwinkle(app.SampleRateHzEditField.Value, app.BPMEditField.Value, [1 1 1 1], [0.001 1 1 1]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MusicPlayerUIFigure = uifigure('Visible', 'off');
            app.MusicPlayerUIFigure.Name = 'Music Player';
            app.UIAxes = uiaxes(app.MusicPlayerUIFigure);
            title(app.UIAxes, 'ADSR Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlayFurEliseButton = uibutton(app.MusicPlayerUIFigure, 'push');
            app.PlayFurEliseButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFurEliseButtonPushed, true);
            app.PlayFurEliseButton.Text = 'Play Fur Elise';
            app.SampleRateHzEditFieldLabel = uilabel(app.MusicPlayerUIFigure);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.MusicPlayerUIFigure, 'numeric');
            app.SampleRateHzEditField.Value = 8000;
            app.BPMEditFieldLabel = uilabel(app.MusicPlayerUIFigure);
            app.BPMEditFieldLabel.Text = 'BPM';
            app.BPMEditField = uieditfield(app.MusicPlayerUIFigure, 'numeric');
            app.BPMEditField.Value = 120;
            app.FinalAttackSliderLabel = uilabel(app.MusicPlayerUIFigure);
            app.FinalAttackSliderLabel.Text = 'Final Attack';
            app.FinalAttackSlider = uislider(app.MusicPlayerUIFigure);
            app.FinalAttackSlider.Limits = [0 1];
            app.FinalAttackSlider.ValueChangedFcn = createCallbackFcn(app, @FinalAttackSliderValueChanged, true);
            app.FinalAttackSlider.Value = 1;
            app.FinalDecaySliderLabel = uilabel(app.MusicPlayerUIFigure);
            app.FinalDecaySliderLabel.Text = 'Final Decay';
            app.FinalDecaySlider = uislider(app.MusicPlayerUIFigure);
            app.FinalDecaySlider.Limits = [0 1];
            app.FinalDecaySlider.ValueChangedFcn = createCallbackFcn(app, @FinalDecaySliderValueChanged, true);
            app.FinalDecaySlider.Value = 0.63;
            app.FinalSustainSliderLabel = uilabel(app.MusicPlayerUIFigure);
            app.FinalSustainSliderLabel.Text = 'Final Sustain';
            app.FinalSustainSlider = uislider(app.MusicPlayerUIFigure);
            app.FinalSustainSlider.Limits = [0 1];
            app.FinalSustainSlider.ValueChangedFcn = createCallbackFcn(app, @FinalSustainSliderValueChanged, true);
            app.FinalSustainSlider.Value = 0.2;
            app.FinalReleaseSliderLabel = uilabel(app.MusicPlayerUIFigure);
            app.FinalReleaseSliderLabel.Text = 'Final Release';
            app.FinalReleaseSlider = uislider(app.MusicPlayerUIFigure);
            app.FinalReleaseSlider.Limits = [0 1];
            app.FinalReleaseSlider.ValueChangedFcn = createCallbackFcn(app, @FinalReleaseSliderValueChanged, true);
            app.AttackTimeSliderLabel = uilabel(app.MusicPlayerUIFigure);
            app.AttackTimeSliderLabel.Text = 'Attack Time';
            app.AttackTimeSlider = uislider(app.MusicPlayerUIFigure);
            app.AttackTimeSlider.Limits = [0.001 1];
            app.AttackTimeSlider.ValueChangedFcn = createCallbackFcn(app, @AttackTimeSliderValueChanged, true);
            app.AttackTimeSlider.Value = 0.053;
            app.DecayTimeSliderLabel = uilabel(app.MusicPlayerUIFigure);
            app.DecayTimeSliderLabel.Text = 'Decay Time';
            app.DecayTimeSlider = uislider(app.MusicPlayerUIFigure);
            app.DecayTimeSlider.Limits = [0.001 1];
            app.DecayTimeSlider.ValueChangedFcn = createCallbackFcn(app, @DecayTimeSliderValueChanged, true);
            app.DecayTimeSlider.Value = 0.093;
            app.SustainTimeSliderLabel = uilabel(app.MusicPlayerUIFigure);
            app.SustainTimeSliderLabel.Text = 'Sustain Time';
            app.SustainTimeSlider = uislider(app.MusicPlayerUIFigure);
            app.SustainTimeSlider.Limits = [0.001 1];
            app.SustainTimeSlider.ValueChangedFcn = createCallbackFcn(app, @SustainTimeSliderValueChanged, true);
            app.SustainTimeSlider.Value = 0.23;
            app.ReleaseTimeSliderLabel = uilabel(app.MusicPlayerUIFigure);
            app.ReleaseTimeSliderLabel.Text = 'Release Time';
            app.ReleaseTimeSlider = uislider(app.MusicPlayerUIFigure);
            app.ReleaseTimeSlider.Limits = [0.001 1];
            app.ReleaseTimeSlider.ValueChangedFcn = createCallbackFcn(app, @ReleaseTimeSliderValueChanged, true);
            app.ReleaseTimeSlider.Value = 0.12;
            app.AttackTime = uieditfield(app.MusicPlayerUIFigure, 'numeric');
            app.AttackTime.Limits = [0.001 Inf];
            app.AttackTime.ValueChangedFcn = createCallbackFcn(app, @AttackTimeValueChanged, true);
            app.AttackTime.Value = 0.053;
            app.SustainLevel = uieditfield(app.MusicPlayerUIFigure, 'numeric');
            app.SustainLevel.ValueChangedFcn = createCallbackFcn(app, @SustainLevelValueChanged, true);
            app.SustainLevel.Value = 0.2;
            app.AttackLevel = uieditfield(app.MusicPlayerUIFigure, 'numeric');
            app.AttackLevel.ValueChangedFcn = createCallbackFcn(app, @AttackLevelValueChanged, true);
            app.AttackLevel.Value = 1;
            app.DecayLevel = uieditfield(app.MusicPlayerUIFigure, 'numeric');
            app.DecayLevel.ValueChangedFcn = createCallbackFcn(app, @DecayLevelValueChanged, true);
            app.DecayLevel.Value = 0.63;
            app.ReleaseLevel = uieditfield(app.MusicPlayerUIFigure, 'numeric');
            app.ReleaseLevel.ValueChangedFcn = createCallbackFcn(app, @ReleaseLevelValueChanged, true);
            app.DecayTime = uieditfield(app.MusicPlayerUIFigure, 'numeric');
            app.DecayTime.Limits = [0.001 Inf];
            app.DecayTime.ValueChangedFcn = createCallbackFcn(app, @DecayTimeValueChanged, true);
            app.DecayTime.Value = 0.093;
            app.SustainTime = uieditfield(app.MusicPlayerUIFigure, 'numeric');
            app.SustainTime.Limits = [0.001 Inf];
            app.SustainTime.ValueChangedFcn = createCallbackFcn(app, @SustainTimeValueChanged, true);
            app.SustainTime.Value = 0.23;
            app.ReleaseTime = uieditfield(app.MusicPlayerUIFigure, 'numeric');
            app.ReleaseTime.ValueChangedFcn = createCallbackFcn(app, @ReleaseTimeValueChanged, true);
            app.ReleaseTime.Value = 0.12;
            app.PlayTwinkleTwinkleButton = uibutton(app.MusicPlayerUIFigure, 'push');
            app.PlayTwinkleTwinkleButton.ButtonPushedFcn = createCallbackFcn(app, @PlayTwinkleTwinkleButtonPushed, true);
            app.PlayTwinkleTwinkleButton.Text = 'Play Twinkle Twinkle';
            app.PlayTwinklenoADSRButton = uibutton(app.MusicPlayerUIFigure, 'push');
            app.PlayTwinklenoADSRButton.ButtonPushedFcn = createCallbackFcn(app, @PlayTwinklenoADSRButtonPushed, true);
            app.PlayTwinklenoADSRButton.Text = 'Play Twinkle (no ADSR)';
            app.StopSoundsButton = uibutton(app.MusicPlayerUIFigure, 'push');
            app.StopSoundsButton.ButtonPushedFcn = createCallbackFcn(app, @StopSoundsButtonPushed, true);
            app.StopSoundsButton.Text = 'Stop Sounds';
            app.MusicPlayerLabel = uilabel(app.MusicPlayerUIFigure);
            app.MusicPlayerLabel.Text = 'Music Player';
            % Show the figure after all components are created
            app.MusicPlayerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlayingSong_Part_E

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MusicPlayerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MusicPlayerUIFigure)
        end
    end
end