classdef dialerapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TelephoneDialerUIFigure  matlab.ui.Figure
        TextArea                 matlab.ui.control.TextArea
        Button_11                matlab.ui.control.Button
        Button_10                matlab.ui.control.Button
        star                     matlab.ui.control.Button
        pound                    matlab.ui.control.Button
        Button_0                 matlab.ui.control.Button
        Button_9                 matlab.ui.control.Button
        Button_8                 matlab.ui.control.Button
        Button_7                 matlab.ui.control.Button
        Button_6                 matlab.ui.control.Button
        Button_5                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button_1                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        duration = 0.2; % Duration of tone
        fs = 8000; % Sampling rate
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "1";
            f1 = 697;
            f2 = 1209;
            t = 0:1/app.fs:app.duration;
            dial_tone = sin(2 * pi * f1 * t) + sin(2 * pi * f2 * t); 
            dial_tone = dial_tone / max(abs(dial_tone)); % Normalize to stop clipping
            sound(dial_tone, app.fs); % Play the sound
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "2";
            f1 = 697;
            f2 = 1336;
            t = 0:1/app.fs:app.duration;
            dial_tone = sin(2 * pi * f1 * t) + sin(2 * pi * f2 * t); 
            dial_tone = dial_tone / max(abs(dial_tone)); % Normalize to stop clipping
            sound(dial_tone, app.fs); % Play the sound
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "3";
            f1 = 697;
            f2 = 1477;
            t = 0:1/app.fs:app.duration;
            dial_tone = sin(2 * pi * f1 * t) + sin(2 * pi * f2 * t); 
            dial_tone = dial_tone / max(abs(dial_tone)); % Normalize to stop clipping
            sound(dial_tone, app.fs); % Play the sound
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "4";
            f1 = 770;
            f2 = 1209;
            t = 0:1/app.fs:app.duration;
            dial_tone = sin(2 * pi * f1 * t) + sin(2 * pi * f2 * t); 
            dial_tone = dial_tone / max(abs(dial_tone)); % Normalize to stop clipping
            sound(dial_tone, app.fs); % Play the sound
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "5";
            f1 = 770;
            f2 = 1336;
            t = 0:1/app.fs:app.duration;
            dial_tone = sin(2 * pi * f1 * t) + sin(2 * pi * f2 * t); 
            dial_tone = dial_tone / max(abs(dial_tone)); % Normalize to stop clipping
            sound(dial_tone, app.fs); % Play the sound
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "6";
            f1 = 770;
            f2 = 1477;
            t = 0:1/app.fs:app.duration;
            dial_tone = sin(2 * pi * f1 * t) + sin(2 * pi * f2 * t); 
            dial_tone = dial_tone / max(abs(dial_tone)); % Normalize to stop clipping
            sound(dial_tone, app.fs); % Play the sound
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "7";
            f1 = 852;
            f2 = 1209;
            t = 0:1/app.fs:app.duration;
            dial_tone = sin(2 * pi * f1 * t) + sin(2 * pi * f2 * t); 
            dial_tone = dial_tone / max(abs(dial_tone)); % Normalize to stop clipping
            sound(dial_tone, app.fs); % Play the sound
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "8";
            f1 = 852;
            f2 = 1336;
            t = 0:1/app.fs:app.duration;
            dial_tone = sin(2 * pi * f1 * t) + sin(2 * pi * f2 * t); 
            dial_tone = dial_tone / max(abs(dial_tone)); % Normalize to stop clipping
            sound(dial_tone, app.fs); % Play the sound
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "9";
            f1 = 852;
            f2 = 1477;
            t = 0:1/app.fs:app.duration;
            dial_tone = sin(2 * pi * f1 * t) + sin(2 * pi * f2 * t); 
            dial_tone = dial_tone / max(abs(dial_tone)); % Normalize to stop clipping
            sound(dial_tone, app.fs); % Play the sound
        end

        % Button pushed function: Button_0
        function Button_0Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "0";
            f1 = 941;
            f2 = 1209;
            t = 0:1/app.fs:app.duration;
            dial_tone = sin(2 * pi * f1 * t) + sin(2 * pi * f2 * t); 
            dial_tone = dial_tone / max(abs(dial_tone)); % Normalize to stop clipping
            sound(dial_tone, app.fs); % Play the sound
        end

        % Button pushed function: star
        function starButtonPushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "*";
            f1 = 941;
            f2 = 1336;
            t = 0:1/app.fs:app.duration;
            dial_tone = sin(2 * pi * f1 * t) + sin(2 * pi * f2 * t); 
            dial_tone = dial_tone / max(abs(dial_tone)); % Normalize to stop clipping
            sound(dial_tone, app.fs); % Play the sound
        end

        % Button pushed function: pound
        function poundButtonPushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "#";
            f1 = 941;
            f2 = 1477;
            t = 0:1/app.fs:app.duration;
            dial_tone = sin(2 * pi * f1 * t) + sin(2 * pi * f2 * t); 
            dial_tone = dial_tone / max(abs(dial_tone)); % Normalize to stop clipping
            sound(dial_tone, app.fs); % Play the sound
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)

        end

        % Callback function: not associated with a component
        function TextAreaValueChanged2(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TelephoneDialerUIFigure = uifigure('Visible', 'off');
            app.TelephoneDialerUIFigure.Name = 'Telephone Dialer';
            app.Button_1 = uibutton(app.TelephoneDialerUIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.TelephoneDialerUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.TelephoneDialerUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.TelephoneDialerUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.TelephoneDialerUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.TelephoneDialerUIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.TelephoneDialerUIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.TelephoneDialerUIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.TelephoneDialerUIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_0 = uibutton(app.TelephoneDialerUIFigure, 'push');
            app.Button_0.ButtonPushedFcn = createCallbackFcn(app, @Button_0Pushed, true);
            app.Button_0.Text = '0';
            app.pound = uibutton(app.TelephoneDialerUIFigure, 'push');
            app.pound.ButtonPushedFcn = createCallbackFcn(app, @poundButtonPushed, true);
            app.pound.Text = '#';
            app.star = uibutton(app.TelephoneDialerUIFigure, 'push');
            app.star.ButtonPushedFcn = createCallbackFcn(app, @starButtonPushed, true);
            app.star.Text = '*';
            app.Button_10 = uibutton(app.TelephoneDialerUIFigure, 'push');
            app.Button_10.Icon = fullfile(pathToMLAPP, 'call.png');
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.TelephoneDialerUIFigure, 'push');
            app.Button_11.Icon = fullfile(pathToMLAPP, 'hang-up.png');
            app.Button_11.Text = '';
            app.TextArea = uitextarea(app.TelephoneDialerUIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            % Show the figure after all components are created
            app.TelephoneDialerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialerapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TelephoneDialerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TelephoneDialerUIFigure)
        end
    end
end